classdef lab4_func_plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Description                matlab.ui.control.TextArea
        c0EditFieldLabel           matlab.ui.control.Label
        c0                         matlab.ui.control.NumericEditField
        c1EditFieldLabel           matlab.ui.control.Label
        c1                         matlab.ui.control.NumericEditField
        c2EditFieldLabel           matlab.ui.control.Label
        c2                         matlab.ui.control.NumericEditField
        c3EditFieldLabel           matlab.ui.control.Label
        c3                         matlab.ui.control.NumericEditField
        c4EditFieldLabel           matlab.ui.control.Label
        c4                         matlab.ui.control.NumericEditField
        StepSizeSliderLabel        matlab.ui.control.Label
        StepSizeSlider             matlab.ui.control.Slider
        Plot                       matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        LowerXBoundEditFieldLabel  matlab.ui.control.Label
        LowerXBound                matlab.ui.control.NumericEditField
        UpperXBoundEditFieldLabel  matlab.ui.control.Label
        UpperXBound                matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Plot
        function PlotButtonPushed(app, event)
            func = @(x) (app.c0.Value * x^4) + (app.c1.Value * x^3) + (app.c2.Value * x^2) + (app.c3.Value * x) + app.c4.Value;
            dx = app.StepSizeSlider.Value;
            xlower = app.LowerXBound.Value;
            xupper = app.UpperXBound.Value;
            xSpan = xlower:dx:xupper;
            N = length(xSpan);
            ySpan = zeros(1, N);
            for i = 1:N
                ySpan(i) = func(xSpan(i));
            end
            plot(app.UIAxes,xSpan, ySpan )
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Description = uitextarea(app.UIFigure);
            app.Description.Value = {'Enter five values, c0 through c4 to be the coefficients and constant for the equation: '; '                                        c0x^4 + c1x^3+c2x^2+c3x - c4'; 'then enter the upper and lower range for the x axis and use the slider to adjust the resolution of the plot. Finally, press "Plot" to plot the function.'};
            app.c0EditFieldLabel = uilabel(app.UIFigure);
            app.c0EditFieldLabel.Text = 'c0';
            app.c0 = uieditfield(app.UIFigure, 'numeric');
            app.c1EditFieldLabel = uilabel(app.UIFigure);
            app.c1EditFieldLabel.Text = 'c1';
            app.c1 = uieditfield(app.UIFigure, 'numeric');
            app.c2EditFieldLabel = uilabel(app.UIFigure);
            app.c2EditFieldLabel.Text = 'c2';
            app.c2 = uieditfield(app.UIFigure, 'numeric');
            app.c3EditFieldLabel = uilabel(app.UIFigure);
            app.c3EditFieldLabel.Text = 'c3';
            app.c3 = uieditfield(app.UIFigure, 'numeric');
            app.c4EditFieldLabel = uilabel(app.UIFigure);
            app.c4EditFieldLabel.Text = 'c4';
            app.c4 = uieditfield(app.UIFigure, 'numeric');
            app.StepSizeSliderLabel = uilabel(app.UIFigure);
            app.StepSizeSliderLabel.Text = 'Step Size';
            app.StepSizeSlider = uislider(app.UIFigure);
            app.StepSizeSlider.Limits = [0 1];
            app.StepSizeSlider.Value = 1;
            app.Plot = uibutton(app.UIFigure, 'push');
            app.Plot.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.Plot.Text = 'Plot';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Function Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.LowerXBoundEditFieldLabel = uilabel(app.UIFigure);
            app.LowerXBoundEditFieldLabel.Text = 'Lower  X Bound';
            app.LowerXBound = uieditfield(app.UIFigure, 'numeric');
            app.LowerXBound.Value = -5;
            app.UpperXBoundEditFieldLabel = uilabel(app.UIFigure);
            app.UpperXBoundEditFieldLabel.Text = 'Upper X Bound';
            app.UpperXBound = uieditfield(app.UIFigure, 'numeric');
            app.UpperXBound.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab4_func_plot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end