classdef lab4_game < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GameDescriptionTextAreaLabel    matlab.ui.control.Label
        GameDescription                 matlab.ui.control.TextArea
        GuessesMadeEditFieldLabel       matlab.ui.control.Label
        GuessesMade                     matlab.ui.control.NumericEditField
        GuessesRemainingEditFieldLabel  matlab.ui.control.Label
        GuessesRemainin                 matlab.ui.control.NumericEditField
        CurrentGuessEditFieldLabel      matlab.ui.control.Label
        CurrentGuess                    matlab.ui.control.NumericEditField
        MakeGuessButton                 matlab.ui.control.Button
        HigherOrLowerEditFieldLabel     matlab.ui.control.Label
        HigherOrLower                   matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        r =  randi([-100 100]);
        y =[];
        x = [];
        correctGuess = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MakeGuessButton
        function MakeGuessButtonPushed(app, event)
            allowedGuesses = 10;
            previousGuessesRemaing = app.GuessesRemainin.Value;
            app.GuessesRemainin.Value = previousGuessesRemaing - 1;
            previousGuessesMade = app.GuessesMade.Value;
            app.GuessesMade.Value = previousGuessesMade + 1;
            curr = app.CurrentGuess.Value;           
            app.x(app.GuessesMade.Value) = previousGuessesMade;
            
            if previousGuessesMade == allowedGuesses || app.correctGuess
                if app.correctGuess
                    str1 = "You already Won!" + newline + " You correctly guessed: ";
                    str2 = num2str(app.r);
                    str3 = "!";
                    app.HigherOrLower.Value = str1 + str2 + str3;
                else
                    str1 = "You are all out of guesses!";
                    str2 = newline;
                    str3 = "You Lose.";
                    str4 = newline;
                    str5 = "The correct number was: ";
                    str6 = num2str(app.r);
                    str7 = ".";
                    app.HigherOrLower.Value = str1 + str2 + str3 + str4 + str5 + str6 + str7;
                    app.x(app.GuessesMade.Value) = previousGuessesMade;
                    app.y(app.GuessesMade.Value) = app.r;
                    plot(app.UIAxes, app.x, app.y, 'r -*', 'LineWidth',1);
                end
            elseif curr < app.r || curr > app.r
                if curr < app.r
                    app.HigherOrLower.Value = "Higher";
                else
                    app.HigherOrLower.Value = "Lower";
                end
                app.y(app.GuessesMade.Value) = curr;
                plot(app.UIAxes, app.x, app.y,'g -*', 'LineWidth',1.25)
            else
                app.HigherOrLower.Value = "Correct!";
                app.correctGuess = true;
                app.y(app.GuessesMade.Value) = curr;
                plot(app.UIAxes, app.x, app.y, 'g -*', 'LineWidth',1.5);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GameDescriptionTextAreaLabel = uilabel(app.UIFigure);
            app.GameDescriptionTextAreaLabel.Text = 'Game Description';
            app.GameDescription = uitextarea(app.UIFigure);
            app.GameDescription.Value = {'You have 10 guesses to guess a random integer between -100 and 100, the game will then plot all your guesses on a plot with (hopefully) the correct guess at the end. '};
            app.GuessesMadeEditFieldLabel = uilabel(app.UIFigure);
            app.GuessesMadeEditFieldLabel.Text = 'Guesses Made';
            app.GuessesMade = uieditfield(app.UIFigure, 'numeric');
            app.GuessesRemainingEditFieldLabel = uilabel(app.UIFigure);
            app.GuessesRemainingEditFieldLabel.Text = 'Guesses Remaining';
            app.GuessesRemainin = uieditfield(app.UIFigure, 'numeric');
            app.GuessesRemainin.Value = 10;
            app.CurrentGuessEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentGuessEditFieldLabel.Text = 'Current Guess';
            app.CurrentGuess = uieditfield(app.UIFigure, 'numeric');
            app.MakeGuessButton = uibutton(app.UIFigure, 'push');
            app.MakeGuessButton.ButtonPushedFcn = createCallbackFcn(app, @MakeGuessButtonPushed, true);
            app.MakeGuessButton.Text = 'Make Guess';
            app.HigherOrLowerEditFieldLabel = uilabel(app.UIFigure);
            app.HigherOrLowerEditFieldLabel.Text = 'Higher Or Lower';
            app.HigherOrLower = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab4_game

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end