classdef project3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        DescriptionTextAreaLabel  matlab.ui.control.Label
        Description               matlab.ui.control.TextArea
        InitialConditionsLabel    matlab.ui.control.Label
        x1t0EditFieldLabel        matlab.ui.control.Label
        x1t0                      matlab.ui.control.NumericEditField
        x1t0EditField_2Label      matlab.ui.control.Label
        x1primet0                 matlab.ui.control.NumericEditField
        x2t0EditFieldLabel        matlab.ui.control.Label
        x2t0                      matlab.ui.control.NumericEditField
        x2t0EditField_2Label      matlab.ui.control.Label
        x2primet0                 matlab.ui.control.NumericEditField
        SpringConstantsLabel      matlab.ui.control.Label
        k1EditFieldLabel          matlab.ui.control.Label
        k1                        matlab.ui.control.NumericEditField
        k2EditFieldLabel          matlab.ui.control.Label
        k2                        matlab.ui.control.NumericEditField
        k3EditFieldLabel          matlab.ui.control.Label
        k3                        matlab.ui.control.NumericEditField
        DampingCoefficientsLabel  matlab.ui.control.Label
        b1EditFieldLabel          matlab.ui.control.Label
        b1                        matlab.ui.control.NumericEditField
        b2EditFieldLabel          matlab.ui.control.Label
        b2                        matlab.ui.control.NumericEditField
        tinitialEditFieldLabel    matlab.ui.control.Label
        tinitial                  matlab.ui.control.NumericEditField
        tfinalEditFieldLabel      matlab.ui.control.Label
        tfinal                    matlab.ui.control.NumericEditField
        UIAxes                    matlab.ui.control.UIAxes
        SolveandPlotButton        matlab.ui.control.Button
        MassesLabel               matlab.ui.control.Label
        m1EditFieldLabel          matlab.ui.control.Label
        m1                        matlab.ui.control.NumericEditField
        m2EditFieldLabel          matlab.ui.control.Label
        m2                        matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SolveandPlotButton
        function SolveandPlotButtonPushed(app, event)
            initcond = [app.x1t0.Value app.x2t0.Value  app.x1primet0.Value app.x2primet0.Value];
            tspan = [app.tinitial.Value app.tfinal.Value];
            
            [t,x] = ode45( @( t,x )odefunc( t,x ), tspan, initcond );
            
            plot( app.UIAxes, t, x( :,1 ), t, x( :,2 ) );
            legend( app.UIAxes, 'Spring 1', 'Spring 2' );
            
            function dxdt = odefunc( t,x )
                dxdt = zeros( length( x ), 1 );
                dxdt(1) = x( 3 );
                dxdt(2) = x( 4 );
                dxdt(3) = ( ( ( app.k1.Value + app.k2.Value ) / app.m1.Value ) * -x( 1 ) ) + ( ( app.k2.Value / app.m1.Value ) * x( 2 ) ) - ( ( app.b1.Value / app.m1.Value ) * x( 3 ) );
                dxdt(4) = ( ( ( app.k2.Value + app.k3.Value ) / app.m2.Value ) * -x( 2 ) ) + ( ( app.k2.Value / app.m2.Value ) * x( 1 ) ) - ( ( app.b2.Value / app.m2.Value ) * x( 4 ) );
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DescriptionTextAreaLabel = uilabel(app.UIFigure);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.Description = uitextarea(app.UIFigure);
            app.Description.Value = {'This GUI takes in 13 values for coefficients, initial conditions, and a lower and upper time, then solves and graphs the coupled 2nd order differential equations.'; '                                                                    m1x1'''' = (-k1-k2) * x1 + k2 * x2 - b1 * x1'''; '                                                                    m2x2'''' = (-k2-k3) * x2 + k2 * x1 - b2 * x2'''};
            app.InitialConditionsLabel = uilabel(app.UIFigure);
            app.InitialConditionsLabel.Text = 'Initial Conditions';
            app.x1t0EditFieldLabel = uilabel(app.UIFigure);
            app.x1t0EditFieldLabel.Text = 'x1(t0)';
            app.x1t0 = uieditfield(app.UIFigure, 'numeric');
            app.x1t0EditField_2Label = uilabel(app.UIFigure);
            app.x1t0EditField_2Label.Text = 'x1''(t0)';
            app.x1primet0 = uieditfield(app.UIFigure, 'numeric');
            app.x2t0EditFieldLabel = uilabel(app.UIFigure);
            app.x2t0EditFieldLabel.Text = 'x2(t0)';
            app.x2t0 = uieditfield(app.UIFigure, 'numeric');
            app.x2t0EditField_2Label = uilabel(app.UIFigure);
            app.x2t0EditField_2Label.Text = 'x2''(t0)';
            app.x2primet0 = uieditfield(app.UIFigure, 'numeric');
            app.SpringConstantsLabel = uilabel(app.UIFigure);
            app.SpringConstantsLabel.Text = 'Spring Constants';
            app.k1EditFieldLabel = uilabel(app.UIFigure);
            app.k1EditFieldLabel.Text = 'k1';
            app.k1 = uieditfield(app.UIFigure, 'numeric');
            app.k2EditFieldLabel = uilabel(app.UIFigure);
            app.k2EditFieldLabel.Text = 'k2';
            app.k2 = uieditfield(app.UIFigure, 'numeric');
            app.k3EditFieldLabel = uilabel(app.UIFigure);
            app.k3EditFieldLabel.Text = 'k3';
            app.k3 = uieditfield(app.UIFigure, 'numeric');
            app.DampingCoefficientsLabel = uilabel(app.UIFigure);
            app.DampingCoefficientsLabel.Text = 'Damping Coefficients';
            app.b1EditFieldLabel = uilabel(app.UIFigure);
            app.b1EditFieldLabel.Text = 'b1';
            app.b1 = uieditfield(app.UIFigure, 'numeric');
            app.b2EditFieldLabel = uilabel(app.UIFigure);
            app.b2EditFieldLabel.Text = 'b2';
            app.b2 = uieditfield(app.UIFigure, 'numeric');
            app.tinitialEditFieldLabel = uilabel(app.UIFigure);
            app.tinitialEditFieldLabel.Text = 'tinitial';
            app.tinitial = uieditfield(app.UIFigure, 'numeric');
            app.tfinalEditFieldLabel = uilabel(app.UIFigure);
            app.tfinalEditFieldLabel.Text = 'tfinal';
            app.tfinal = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Spring Positions')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Position(x)')
            app.SolveandPlotButton = uibutton(app.UIFigure, 'push');
            app.SolveandPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SolveandPlotButtonPushed, true);
            app.SolveandPlotButton.Text = 'Solve and Plot';
            app.MassesLabel = uilabel(app.UIFigure);
            app.MassesLabel.Text = 'Masses';
            app.m1EditFieldLabel = uilabel(app.UIFigure);
            app.m1EditFieldLabel.Text = 'm1';
            app.m1 = uieditfield(app.UIFigure, 'numeric');
            app.m2EditFieldLabel = uilabel(app.UIFigure);
            app.m2EditFieldLabel.Text = 'm2';
            app.m2 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end